classdef MLCompiler_Arduino < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConfigureArduinoUIFigure      matlab.ui.Figure
        CancelButton                  matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        COMPortDropDown               matlab.ui.control.DropDown
        COMPortLabel                  matlab.ui.control.Label
        BoardDropDown                 matlab.ui.control.DropDown
        BoardLabel                    matlab.ui.control.Label
        BrowseButton                  matlab.ui.control.Button
        ArduinoCLIPathEditField       matlab.ui.control.EditField
        ArduinoCLIPathEditFieldLabel  matlab.ui.control.Label
        SubmitButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        arduinoUtilityObject % Holds the arduino Utility object which gets assigned in startup. Used for validating Arduino CLI Path
    end
    
    methods (Access = private)
        
        function labelUpdate = validateCLILocation(app)
            try
                % Validating the CLI path provided.
                validateCLIPath(app.arduinoUtilityObject, app.ArduinoCLIPathEditField.Value);
                % Don't update error label as validation is successful.
                labelUpdate = false;
            catch e
                % display exception in the log
                disp(e);
                labelUpdate = true;
                if ismac
                    % arduinoio.CLIRoot in MAC is
                    % .../Arduino.app/Contents/Java. But the user
                    % cannot choose this location through a browse
                    % window. So here it is assumed that the user gave
                    % the parent directory of Arduino.app. Had this
                    % caused the exception earlier, here it would pass.
                    if strcmpi(e.identifier, 'MATLAB:arduinoio:general:invalidCLIPath')
                        try
                            validateCLIPath(app.arduinoUtilityObject, fullfile(app.ArduinoCLIPathEditField.Value, 'Arduino.app', 'Contents', 'Java'));
                            % Do not update the error label as validation
                            % passed
                            labelUpdate = false;
                        catch
                            % Continue with error label update as validation
                            % failed
                        end
                    end
                end
                if labelUpdate
                    % Validation of CLI Path failed.
                    m = message('MATLAB:arduinoio:general:locationInvalidDeploy');
                    app.Label.Text = m.getString();
                    app.Label.Visible = true;
                    app.Label.FontColor = [1 0 0];
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arduinoUtilityObj, defaultPort, defaultBoard)
            % Move UI to center of the screen
            movegui(app.ConfigureArduinoUIFigure, 'center');
            
            app.arduinoUtilityObject = arduinoUtilityObj;
            % Fill the port dropdown with serialportlist and show the selection
            % from the developer's script
            ports = serialportlist;
            try
                % defaultPort is validated to be one of ports
                value = ports(strcmpi(ports, defaultPort));
                if isempty(value)
                    value = ports(1);
                end
            catch
                % Else, take the first port available.
                value = ports(1);
            end
            app.COMPortDropDown.Items = ports;
            app.COMPortDropDown.Value = value;
            
            % Fill the port dropdown with list of boards and show the selection
            % from the developer's script
            try
                % Get all boards supported by Serial interface
                boards = arduinoio.internal.ArduinoConstants.getSupportedBoards(matlabshared.hwsdk.internal.ConnectionTypeEnum.Serial);
                % defaultBoard is validated to be one of boards
                value = boards{(strcmpi(boards, defaultBoard))};
                if isempty(value)
                    value = boards{1};
                end
            catch
                % Else, take the first board available.
                value = boards{1};
            end
            app.BoardDropDown.Items = boards;
            app.BoardDropDown.Value = value;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            if app.Label.Visible
                app.Label.Visible = false;
            end
            if isfolder(app.ArduinoCLIPathEditField.Value)
                path = uigetdir(app.ArduinoCLIPathEditField.Value);
            else
                path = uigetdir();
            end
            if ~isempty(path) && ~isequal(path, 0)
                app.ArduinoCLIPathEditField.Value = path;
                app.SubmitButton.Enable = true;
                app.SubmitButton.Tooltip = '';
            end
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            % Error if Path is empty
            if isempty(app.ArduinoCLIPathEditField.Value)
                m = message('MATLAB:arduinoio:general:locationReqFieldDeploy');
                app.Label.Text = m.getString();
                app.Label.Visible = true;
                app.Label.FontColor = 'red';
            elseif ~isfolder(app.ArduinoCLIPathEditField.Value)
                % Error if path is not a valid folder
                m = message('MATLAB:arduinoio:general:locationValidFolderDeploy');
                app.Label.Text = m.getString();
                app.Label.Visible = true;
                app.Label.FontColor = 'red';
            else
                m = message('MATLAB:arduinoio:general:locationValidationInfoDeploy');
                disp(m.getString());
                
                labelUpdate = validateCLILocation(app);
                if ~labelUpdate
                    m = message('MATLAB:arduinoio:general:locationValidationSuccessInfoDeploy');
                    disp(m.getString());
                    % Release the lock (uiwait) so that the control can go
                    % forward.
                    uiresume(app.ConfigureArduinoUIFigure);
                end
            end
        end

        % Value changing function: ArduinoCLIPathEditField
        function ArduinoCLIPathEditFieldValueChanging(app, event)
            % Hide the error label and enable the submit button
            app.Label.Visible = false;
            app.SubmitButton.Enable = true;
            app.SubmitButton.Tooltip = '';
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % End User close. Deleting the app. The control should quit.
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConfigureArduinoUIFigure = uifigure('Visible', 'off');
            app.ConfigureArduinoUIFigure.Name = 'Configure Arduino';
            app.SubmitButton = uibutton(app.ConfigureArduinoUIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Enable = 'off';
            app.SubmitButton.Tooltip = {'Arduino CLI Path field must not be empty'};
            app.SubmitButton.Text = 'Submit';
            app.ArduinoCLIPathEditFieldLabel = uilabel(app.ConfigureArduinoUIFigure);
            app.ArduinoCLIPathEditFieldLabel.Text = 'Arduino CLI Path:  ';
            app.ArduinoCLIPathEditField = uieditfield(app.ConfigureArduinoUIFigure, 'text');
            app.ArduinoCLIPathEditField.ValueChangingFcn = createCallbackFcn(app, @ArduinoCLIPathEditFieldValueChanging, true);
            app.BrowseButton = uibutton(app.ConfigureArduinoUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Tooltip = {'Navigate to Arduino CLI installation directory'};
            app.BrowseButton.Text = 'Browse ...';
            app.BoardLabel = uilabel(app.ConfigureArduinoUIFigure);
            app.BoardLabel.Text = 'Board:                      ';
            app.BoardDropDown = uidropdown(app.ConfigureArduinoUIFigure);
            app.BoardDropDown.Items = {};
            app.BoardDropDown.Tooltip = {''};
            app.BoardDropDown.Value = {};
            app.COMPortLabel = uilabel(app.ConfigureArduinoUIFigure);
            app.COMPortLabel.Text = 'COM Port:                ';
            app.COMPortDropDown = uidropdown(app.ConfigureArduinoUIFigure);
            app.COMPortDropDown.Items = {};
            app.COMPortDropDown.Tooltip = {''};
            app.COMPortDropDown.Value = {};
            app.Label = uilabel(app.ConfigureArduinoUIFigure);
            app.Label.Visible = 'off';
            app.CancelButton = uibutton(app.ConfigureArduinoUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.ConfigureArduinoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MLCompiler_Arduino(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConfigureArduinoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConfigureArduinoUIFigure)
        end
    end
end